classdef huffman_fenno_shannon_coding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Menu_huffman                   matlab.ui.container.Menu
        Menu_fenno                     matlab.ui.container.Menu
        Menu_shannon                   matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        readme1NR23excelexcel123Label  matlab.ui.control.Label
        excelButton                    matlab.ui.control.Button
        EditField_Excel_Path           matlab.ui.control.EditField
        Label_9                        matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Label_8                        matlab.ui.control.Label
        TextArea_Hx                    matlab.ui.control.TextArea
        Label_7                        matlab.ui.control.Label
        TextArea_effi                  matlab.ui.control.TextArea
        Label_6                        matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NLabel                         matlab.ui.control.Label
        TextArea_p                     matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        NRN1R2Label                    matlab.ui.control.Label
        eg04050104u105u2Label          matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='Coding_of_Huffman_Shannon_Fenno_3.0_Yuhang_Pang';
        end

        % Menu selected function: Menu_huffman
        function Huffman(app, event)
            %关闭其他按钮的选择
            app.Menu_huffman.Checked="on";
            app.Menu_fenno.Checked="off";
            app.Menu_shannon.Checked="off";
            eval(['p_1=',char(app.TextArea_p.Value),';']);
            n=app.NEditField.Value;
            r=app.REditField.Value;

            % coding
            [code_original,len_average,Hx,effi]=Huffman_coding(p_1,r,n );
            %disp
            app.TextArea_Hx.Value=num2str(Hx);
            app.TextArea_effi.Value=num2str(effi);
            app.TextArea.Value=num2str(len_average);
            app.UITable.Data=code_original';
%             writetable(app.UITable.Data,app.Excel_Path);

        end

        % Menu selected function: Menu_fenno
        function Menu_fennoSelected(app, event)
            app.Menu_huffman.Checked="off";
            app.Menu_shannon.Checked="off";
            app.Menu_fenno.Checked="on";
            app.NEditField.Value=1;
            app.REditField.Value=2;
            %get data
            eval(['p_1=',char(app.TextArea_p.Value),';']);

            %get data

            [code_original_Fenno,len_average_Fenno,H_x_Fenno,efficiency_coding_Fenno]=Fenno_coding(p_1);
            
            %disp
            app.TextArea_Hx.Value=num2str(H_x_Fenno);
            app.TextArea_effi.Value=num2str(efficiency_coding_Fenno);
            app.TextArea.Value=num2str(len_average_Fenno);
            app.UITable.Data=code_original_Fenno';
        end

        % Menu selected function: Menu_shannon
        function Menu_shannonSelected(app, event)
            app.Menu_huffman.Checked="off";
            app.Menu_fenno.Checked="off";
            app.Menu_shannon.Checked="on";
            app.NEditField.Value=1;
            app.REditField.Value=2;
            
            %get data
            eval(['p_1=',char(app.TextArea_p.Value),';']);
            
            %coding
            [code_original_Shannon,len_average_Shannon,H_x_Shannon,efficiency_coding_Shannon] = Shannon_coding(p_1);

            %disp
            app.TextArea_Hx.Value=num2str(H_x_Shannon);
            app.TextArea_effi.Value=num2str(efficiency_coding_Shannon);
            app.TextArea.Value=num2str(len_average_Shannon);
            app.UITable.Data=code_original_Shannon';
        end

        % Button pushed function: excelButton
        function output_excel(app, event)
            Excel_Path=app.EditField_Excel_Path.Value;
            whos Excel_Path
            writecell(app.UITable.Data,Excel_Path);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '编码方式';
            app.Menu_huffman = uimenu(app.Menu);
            app.Menu_huffman.MenuSelectedFcn = createCallbackFcn(app, @Huffman, true);
            app.Menu_huffman.Text = '霍夫曼编码';
            app.Menu_fenno = uimenu(app.Menu);
            app.Menu_fenno.MenuSelectedFcn = createCallbackFcn(app, @Menu_fennoSelected, true);
            app.Menu_fenno.Text = '费诺编码';
            app.Menu_shannon = uimenu(app.Menu);
            app.Menu_shannon.MenuSelectedFcn = createCallbackFcn(app, @Menu_shannonSelected, true);
            app.Menu_shannon.Text = '香农编码';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'编码输出'};
            app.UITable.RowName = {};
            app.eg04050104u105u2Label = uilabel(app.GridLayout);
            app.eg04050104u105u2Label.Text = '输入要求：和为1的列表，注意使用英文逗号和英文中括号，eg：[0.4,0.5,0.1]（默认0.4为符号u1，0.5为符号u2……）';
            app.NRN1R2Label = uilabel(app.GridLayout);
            app.NRN1R2Label.Text = {'N与R仅仅霍夫曼编码有效'; '输入要求：整数，N大于1，R大于2'};
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '信源概率';
            app.TextArea_p = uitextarea(app.GridLayout);
            app.TextArea_p.Value = {'[0.4,0.6]'};
            app.NLabel = uilabel(app.GridLayout);
            app.NLabel.Text = 'N重符号序列：';
            app.NEditField = uieditfield(app.GridLayout, 'numeric');
            app.NEditField.Limits = [1 10];
            app.NEditField.Value = 1;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'R进制：';
            app.REditField = uieditfield(app.GridLayout, 'numeric');
            app.REditField.Limits = [2 10];
            app.REditField.Value = 2;
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '编码效率';
            app.TextArea_effi = uitextarea(app.GridLayout);
            app.TextArea_effi.Editable = 'off';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '信源熵';
            app.TextArea_Hx = uitextarea(app.GridLayout);
            app.TextArea_Hx.Editable = 'off';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '平均码长';
            app.TextArea = uitextarea(app.GridLayout);
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '输出绝对路径';
            app.EditField_Excel_Path = uieditfield(app.GridLayout, 'text');
            app.EditField_Excel_Path.Value = 'D:\coding.xls';
            app.excelButton = uibutton(app.GridLayout, 'push');
            app.excelButton.ButtonPushedFcn = createCallbackFcn(app, @output_excel, true);
            app.excelButton.Text = '输出为excel';
            app.readme1NR23excelexcel123Label = uilabel(app.GridLayout);
            app.readme1NR23excelexcel123Label.Text = {'使用说明（readme）：（1）先按照要求输入信源概率，符号序列数N，进制R （2）再从菜单选择编码方式'; '（3）如果要将结果输出为excel文件，请先输入绝对路径，再点击“输出为excel”按钮（没有文件时将自动创建）'; '禁止：（1）输出的绝对路径禁止含有中文！！（2）禁止输入不合规的数据后选择菜单选项（3）禁止使用中文字符'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = huffman_fenno_shannon_coding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end